classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        DropDown                       matlab.ui.control.DropDown
        Panel                          matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        Button_7                       matlab.ui.control.Button
        group2fftEditField             matlab.ui.control.NumericEditField
        group2fftEditFieldLabel        matlab.ui.control.Label
        group1fftEditField             matlab.ui.control.NumericEditField
        group1fftEditFieldLabel        matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        readOriginalSignal             matlab.ui.control.Button
        TabGroup3                      matlab.ui.container.TabGroup
        Tab_9                          matlab.ui.container.Tab
        GridLayout23                   matlab.ui.container.GridLayout
        EditField_13                   matlab.ui.control.NumericEditField
        Label_22                       matlab.ui.control.Label
        EditField_12                   matlab.ui.control.NumericEditField
        Label_21                       matlab.ui.control.Label
        EditField_15                   matlab.ui.control.NumericEditField
        Label_24                       matlab.ui.control.Label
        Tab_14                         matlab.ui.container.Tab
        GridLayout26                   matlab.ui.container.GridLayout
        DropDown_3                     matlab.ui.control.DropDown
        Label_33                       matlab.ui.control.Label
        Spinner_3                      matlab.ui.control.Spinner
        Label_32                       matlab.ui.control.Label
        EditField_17                   matlab.ui.control.NumericEditField
        Label_31                       matlab.ui.control.Label
        Tab_4                          matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        Spinner                        matlab.ui.control.Spinner
        Label_8                        matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        Label_6                        matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        Tab_13                         matlab.ui.container.Tab
        GridLayout24                   matlab.ui.container.GridLayout
        pointsLabel_2                  matlab.ui.control.Label
        NaNLabel_10                    matlab.ui.control.Label
        Label_28                       matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
        NaNLabel_9                     matlab.ui.control.Label
        Label_27                       matlab.ui.control.Label
        usLabel_2                      matlab.ui.control.Label
        NaNLabel_8                     matlab.ui.control.Label
        Label_26                       matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        NaNLabel_7                     matlab.ui.control.Label
        Label_25                       matlab.ui.control.Label
        EditField_14                   matlab.ui.control.NumericEditField
        Label_23                       matlab.ui.control.Label
        Tab_5                          matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        EditField_3                    matlab.ui.control.NumericEditField
        Label_7                        matlab.ui.control.Label
        Tab_15                         matlab.ui.container.Tab
        GridLayout27                   matlab.ui.container.GridLayout
        DropDown_4                     matlab.ui.control.DropDown
        Label_36                       matlab.ui.control.Label
        Spinner_4                      matlab.ui.control.Spinner
        Label_35                       matlab.ui.control.Label
        EditField_18                   matlab.ui.control.NumericEditField
        Label_34                       matlab.ui.control.Label
        group1Panel                    matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        pointsLabel                    matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        usLabel                        matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        samplePoints                   matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        sampleTime                     matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        sampleInterval                 matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        sampleRate                     matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        Tab_2                          matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        group2fft                      matlab.ui.control.UIAxes
        group2                         matlab.ui.control.UIAxes
        group1fft                      matlab.ui.control.UIAxes
        group1                         matlab.ui.control.UIAxes
        Tab_3                          matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        UIAxes5                        matlab.ui.control.UIAxes
        Tab_6                          matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        NaNLabel_5                     matlab.ui.control.Label
        TabGroup4                      matlab.ui.container.TabGroup
        Tab_7                          matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        NaNLabel_12                    matlab.ui.control.Label
        Label_30                       matlab.ui.control.Label
        NaNLabel_11                    matlab.ui.control.Label
        Label_29                       matlab.ui.control.Label
        EditField_16                   matlab.ui.control.NumericEditField
        Label_18                       matlab.ui.control.Label
        Button_4                       matlab.ui.control.Button
        Panel_5                        matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        TooMuchLevelsCheckBox          matlab.ui.control.CheckBox
        NormalizeCheckBox              matlab.ui.control.CheckBox
        sdEditField                    matlab.ui.control.NumericEditField
        sdEditFieldLabel               matlab.ui.control.Label
        hBookEditField                 matlab.ui.control.NumericEditField
        hBookEditFieldLabel            matlab.ui.control.Label
        IncludeBaselinePlotsEditField  matlab.ui.control.NumericEditField
        IncludeBaselinePlotsEditFieldLabel  matlab.ui.control.Label
        BaselineLengthEditField        matlab.ui.control.NumericEditField
        BaselineLengthEditFieldLabel   matlab.ui.control.Label
        ImpulsionLimitEditField        matlab.ui.control.NumericEditField
        ImpulsionLimitEditFieldLabel   matlab.ui.control.Label
        sigmaEditField                 matlab.ui.control.NumericEditField
        sigmaEditFieldLabel            matlab.ui.control.Label
        deltaEditField                 matlab.ui.control.NumericEditField
        deltaEditFieldLabel            matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        EditField_6                    matlab.ui.control.NumericEditField
        Label_12                       matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        Label_11                       matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        Label_10                       matlab.ui.control.Label
        UIAxes6                        matlab.ui.control.UIAxes
        Tab_8                          matlab.ui.container.Tab
        GridLayout15                   matlab.ui.container.GridLayout
        TabGroup5                      matlab.ui.container.TabGroup
        Tab_10                         matlab.ui.container.Tab
        GridLayout19                   matlab.ui.container.GridLayout
        Panel3                         matlab.ui.container.Panel
        GridLayout16                   matlab.ui.container.GridLayout
        DropDown_5                     matlab.ui.control.DropDown
        Label_37                       matlab.ui.control.Label
        Button_6                       matlab.ui.control.Button
        NaNNaNLabel                    matlab.ui.control.Label
        Panel_7                        matlab.ui.container.Panel
        GridLayout18                   matlab.ui.container.GridLayout
        Label_17                       matlab.ui.control.Label
        EditField_11                   matlab.ui.control.NumericEditField
        EditField_10                   matlab.ui.control.NumericEditField
        EditField_9                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        Label_19                       matlab.ui.control.Label
        Spinner_2                      matlab.ui.control.Spinner
        Label_15                       matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        EditField_7                    matlab.ui.control.NumericEditField
        Label_14                       matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        UIAxes8_8                      matlab.ui.control.UIAxes
        UIAxes8_9                      matlab.ui.control.UIAxes
        UIAxes8_7                      matlab.ui.control.UIAxes
        UIAxes8_6                      matlab.ui.control.UIAxes
        UIAxes8_5                      matlab.ui.control.UIAxes
        UIAxes8_4                      matlab.ui.control.UIAxes
        UIAxes8_3                      matlab.ui.control.UIAxes
        UIAxes8_2                      matlab.ui.control.UIAxes
        UIAxes8                        matlab.ui.control.UIAxes
        Tab_12                         matlab.ui.container.Tab
        GridLayout22                   matlab.ui.container.GridLayout
        GridLayout25                   matlab.ui.container.GridLayout
        holdonCheckBox                 matlab.ui.control.CheckBox
        Button_8                       matlab.ui.control.Button
        UIAxes12                       matlab.ui.control.UIAxes
        UIAxes13                       matlab.ui.control.UIAxes
        UIAxes11                       matlab.ui.control.UIAxes
        Tab_16                         matlab.ui.container.Tab
        GridLayout28                   matlab.ui.container.GridLayout
        TabGroup6                      matlab.ui.container.TabGroup
        Tab_17                         matlab.ui.container.Tab
        GridLayout29                   matlab.ui.container.GridLayout
        holdonCheckBox_2               matlab.ui.control.CheckBox
        Button_9                       matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        UIAxes14                       matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu_2                         matlab.ui.container.Menu
        Menu_3                         matlab.ui.container.Menu
        Menu_5                         matlab.ui.container.Menu
        Menu_6                         matlab.ui.container.Menu
        Menu_4                         matlab.ui.container.Menu
        Menu_7                         matlab.ui.container.Menu
        Menu_8                         matlab.ui.container.Menu
        Menu_9                         matlab.ui.container.Menu
        xMenu                          matlab.ui.container.Menu
        xMenu_2                        matlab.ui.container.Menu
        Menu_24                        matlab.ui.container.Menu
        nsMenu_2                       matlab.ui.container.Menu
        usMenu_3                       matlab.ui.container.Menu
        msMenu_3                       matlab.ui.container.Menu
        sMenu_3                        matlab.ui.container.Menu
        Menu_25                        matlab.ui.container.Menu
        HzMenu_2                       matlab.ui.container.Menu
        kHzMenu_2                      matlab.ui.container.Menu
        yMenu                          matlab.ui.container.Menu
        yMenu_2                        matlab.ui.container.Menu
        Menu_10                        matlab.ui.container.Menu
        Menu_11                        matlab.ui.container.Menu
        Menu_12                        matlab.ui.container.Menu
        Menu_13                        matlab.ui.container.Menu
        Menu_14                        matlab.ui.container.Menu
        Menu_15                        matlab.ui.container.Menu
        Menu_16                        matlab.ui.container.Menu
        Menu_17                        matlab.ui.container.Menu
        Menu_18                        matlab.ui.container.Menu
        Menu_19                        matlab.ui.container.Menu
        yMenu_3                        matlab.ui.container.Menu
        Menu_23                        matlab.ui.container.Menu
        pAMenu_3                       matlab.ui.container.Menu
        nAMenu_3                       matlab.ui.container.Menu
        uAMenu_2                       matlab.ui.container.Menu
        mAMenu_3                       matlab.ui.container.Menu
        AMenu_3                        matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        Menu_20                        matlab.ui.container.Menu
        xMenu_3                        matlab.ui.container.Menu
        Menu_21                        matlab.ui.container.Menu
        nsMenu                         matlab.ui.container.Menu
        usMenu_2                       matlab.ui.container.Menu
        msMenu_2                       matlab.ui.container.Menu
        sMenu_2                        matlab.ui.container.Menu
        Menu_26                        matlab.ui.container.Menu
        HzMenu_3                       matlab.ui.container.Menu
        kHzMenu_3                      matlab.ui.container.Menu
        yMenu_4                        matlab.ui.container.Menu
        Menu_22                        matlab.ui.container.Menu
        pAMenu_2                       matlab.ui.container.Menu
        nAMenu_2                       matlab.ui.container.Menu
        uAMenu                         matlab.ui.container.Menu
        mAMenu_2                       matlab.ui.container.Menu
        AMenu_2                        matlab.ui.container.Menu
        ContextMenu3                   matlab.ui.container.ContextMenu
        XscaleMenu                     matlab.ui.container.Menu
        linearMenu                     matlab.ui.container.Menu
        logMenu                        matlab.ui.container.Menu
        YscaleMenu                     matlab.ui.container.Menu
        linearMenu_2                   matlab.ui.container.Menu
        logMenu_2                      matlab.ui.container.Menu
        ContextMenu4                   matlab.ui.container.ContextMenu
        Menu_27                        matlab.ui.container.Menu
        importFile                     matlab.ui.container.Menu
        clearFile                      matlab.ui.container.Menu
        Menu_31                        matlab.ui.container.Menu
        Menu_32                        matlab.ui.container.Menu
        Menu_33                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        filePath;
        originalSignal;fftOriginalSignal;
        originalSignalTimeIdx;originalSignalFreqIdx;
        pretreatmentSignal;fftPretreatmentSignal;eventFit;pickeventFit;
        pretreatmentSignalSampleRate;pretreatmentSignalPointNum;pretreatmentSignalTime;pretreatmentSignalTimeIdx;pretreatmentSignalFreqIdx;
        stagingSignal;fftStagingSignal;
        stagingSignalSampleRate;     stagingSignalPointNum;     stagingSignalTime;     stagingSignalTimeIdx;     stagingSignalFreqIdx;
        dealSignal;
        dealSignalSampleRate;        dealSignalPointNum;        dealSignalTime;        dealSignalTimeIdx;
        baseline;
        tempplot;tempplot1;
        eventIdx;eventArray;eventmin;
        dwellTime;dwellDeepth;
        pickdwellTime;pickdwellDeepth;pickeventIdx;
        plotEventFigure;
        ParameterFileExtension = '.txt';  % 参数文件扩展名
        DefaultParameterFileName = 'app_parameters';  % 默认参数文件名
    end
    
    methods (Access = private)
        
        function displayInit(app)
            cla(app.group1);
            cla(app.group1fft);
            cla(app.group2);
            cla(app.group2fft);
            cla(app.UIAxes6);
            cla(app.UIAxes5);
            for i=1:9
                cla(app.plotEventFigure{i});
                app.plotEventFigure{i}.Title.String='';
            end
            app.sampleRate.Text = 'NaN';
            app.sampleInterval.Text = 'NaN';
            app.sampleTime.Text = 'NaN';
            app.samplePoints.Text = 'NaN';
            app.NaNLabel_7.Text = 'NaN';
            app.NaNLabel_8.Text = 'NaN';
            app.NaNLabel_9.Text = 'NaN';
            app.NaNLabel_12.Text = 'NaN';
            app.NaNLabel_10.Text = 'NaN';
            app.NaNNaNLabel.Text = 'NaN个事件，共NaN页';
            app.NaNLabel_11.Text = 'NaN';
            app.NaNLabel_5.Text = 'NaN';
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            Init__();
            axis(app.group1, 'tight');
            axis(app.group1fft, 'tight');
            axis(app.group2, 'tight');
            axis(app.group2fft, 'tight');
            app.group1fft.XAxis.Exponent = 0;
            app.group2fft.XAxis.Exponent = 0;
            app.plotEventFigure = {app.UIAxes8, app.UIAxes8_2, app.UIAxes8_3, ...
                               app.UIAxes8_4, app.UIAxes8_5, app.UIAxes8_6, ...
                               app.UIAxes8_7, app.UIAxes8_8, app.UIAxes8_9};
        end

        % Menu selected function: importFile
        function importFileSelected(app, event)
            displayInit(app);
            f = figure("Name",'fuben','Position',[-120 -200 100 10]);
            [fileName, app.filePath] = uigetfile('*.abf;*.mat', '选择一个或多个ABF/mat文件', 'MultiSelect', 'on');
            delete(f);
            if isequal(fileName, 0)
                return;
            end
            if ischar(fileName)
                fileName = {fileName};
            end
            app.DropDown.Items=fullfile(app.filePath,fileName);
            app.DropDown.Value=app.DropDown.Items{1};
            app.ListBox.Items=fileName;
            app.ListBox.Value=app.ListBox.Items{1};
        end

        % Menu selected function: clearFile
        function clearFileSelected(app, event)
            displayInit(app);
            app.DropDown.Items={};
        end

        % Button pushed function: readOriginalSignal
        function readOriginalSignalPushed(app, event)
            if ~isempty(app.DropDown.Value)
                displayInit(app);
                [dataMessage]=readFileData(app.DropDown.Value);
                app.originalSignal = dataMessage.signalData;
                app.originalSignalTimeIdx = dataMessage.timeIdx;
                app.originalSignalFreqIdx = dataMessage.freq;
                app.sampleRate.Text = num2str(dataMessage.sampleRate);
                app.sampleInterval.Text = num2str(dataMessage.sampleInterval);
                app.sampleTime.Text = num2str(dataMessage.signalTime);
                app.samplePoints.Text = num2str(dataMessage.signalPointNum);
                fftSignal = abs(fft(app.originalSignal)/dataMessage.signalPointNum);
                fftSignal = fftSignal(1:ceil(dataMessage.signalPointNum/2+1));
                app.fftOriginalSignal = fftSignal;
                app.baseline(1:dataMessage.signalPointNum) = mean(app.originalSignal);
                app.stagingSignal = app.originalSignal;
                app.fftStagingSignal = fftSignal;
                app.stagingSignalSampleRate = dataMessage.sampleRate;
                app.stagingSignalTimeIdx = dataMessage.timeIdx;
                app.stagingSignalPointNum = dataMessage.signalPointNum;
                app.stagingSignalTime = dataMessage.signalTime;
                app.stagingSignalFreqIdx = dataMessage.freq;
                plot(app.group1,dataMessage.timeIdx,app.originalSignal);
                plot(app.group1fft,dataMessage.freq,fftSignal,'Color','r');
                
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            displayInit(app);
        end

        % Button pushed function: Button_7
        function fftyTickMaxSize(app, event)
            if app.group1fftEditField.Value
                ylim(app.group1fft, [0, app.group1fftEditField.Value]);
            else
                ylim(app.group1fft, 'auto');
            end
            if app.group2fftEditField.Value
                ylim(app.group2fft, [0, app.group2fftEditField.Value]);
            else
                ylim(app.group2fft, 'auto');
            end
            if app.xEditField.Value
                xlim(app.UIAxes5,[0, app.xEditField.Value]);
            else
                xlim(app.UIAxes5, 'auto');
            end
        end

        % Button pushed function: Button
        function pretreatment(app, event)
            if isgraphics(app.tempplot)
                delete(app.tempplot);
            end
            cla(app.group2);cla(app.group2fft);
            drawnow;
            if strcmp(app.TabGroup3.SelectedTab.Title,'贝塞尔滤波')
                cutoff = app.EditField.Value;
                pole = app.Spinner.Value;
                type = app.DropDown_2.Value;
                app.pretreatmentSignal = bessfilt(app.stagingSignal,cutoff,pole,app.stagingSignalSampleRate,'type',type);
                app.pretreatmentSignalSampleRate = app.stagingSignalSampleRate;
            elseif strcmp(app.TabGroup3.SelectedTab.Title,'重采样')
                app.pretreatmentSignalSampleRate = app.EditField_14.Value;
                app.pretreatmentSignal = resample(app.stagingSignal,app.pretreatmentSignalSampleRate,ceil(app.stagingSignalSampleRate));
                app.NaNLabel_7.Text = num2str(app.pretreatmentSignalSampleRate);
                app.NaNLabel_8.Text = num2str(1e6/app.pretreatmentSignalSampleRate);
                app.NaNLabel_9.Text = num2str(length(app.pretreatmentSignal)/app.pretreatmentSignalSampleRate);
                app.NaNLabel_10.Text = num2str(length(app.pretreatmentSignal));
            elseif strcmp(app.TabGroup3.SelectedTab.Title,'巴特沃斯滤波')
                cutoff = app.EditField_17.Value;
                pole = app.Spinner_3.Value;
                type = app.DropDown_3.Value;
                app.pretreatmentSignal = butterfilt(app.stagingSignal,cutoff,pole,app.stagingSignalSampleRate,'type',type);
                app.pretreatmentSignalSampleRate = app.stagingSignalSampleRate;
            elseif strcmp(app.TabGroup3.SelectedTab.Title,'切比雪夫滤波')
                cutoff = app.EditField_18.Value;
                pole = app.Spinner_4.Value;
                type = app.DropDown_4.Value;
                app.pretreatmentSignal = chebyfilt(app.stagingSignal,cutoff,pole,app.stagingSignalSampleRate,'type',type);
                app.pretreatmentSignalSampleRate = app.stagingSignalSampleRate;
            elseif strcmp(app.TabGroup3.SelectedTab.Title,'多项式拟合')
                app.pretreatmentSignalSampleRate = app.stagingSignalSampleRate;
                N = length(app.stagingSignal);
                t = 1:N;
                poly1_order = app.EditField_12.Value;
                poly2_order = app.EditField_15.Value;
                window_size = app.EditField_13.Value;
                [p_global, ~, mu] = polyfit(t, app.stagingSignal, poly1_order);
                baseline_global = polyval(p_global, t, [], mu);
                step_size = ceil(N / window_size);
                baseline_local = zeros(N, 1);
                for i = 1:step_size
                    idx_start = (i-1) * window_size + 1;
                    idx_end = min(i * window_size, N);
                    idx = idx_start:idx_end;
                    residual = app.stagingSignal(idx) - baseline_global(idx)';
                    if length(idx) >= poly2_order + 1
                        [p_local, ~, mu_local] = polyfit(t(idx), residual, poly2_order);
                        baseline_local(idx) = polyval(p_local, t(idx), [], mu_local);
                    end
                end
                total_baseline = baseline_global' + baseline_local;
                app.pretreatmentSignal = app.stagingSignal - total_baseline;
                app.baseline = total_baseline;
                hold(app.group1,"on");
                    app.tempplot = plot(app.group1,app.stagingSignalTimeIdx,app.baseline,'Color','r');
                hold(app.group1,"off");
            elseif strcmp(app.TabGroup3.SelectedTab.Title,'移动平均')
                app.pretreatmentSignalSampleRate = app.stagingSignalSampleRate;
                windowSize = app.EditField_3.Value;
                app.baseline = movmean(app.stagingSignal,windowSize);
                baselineSignal = app.stagingSignal - app.baseline;
                app.pretreatmentSignal = mean(app.stagingSignal) - mean(baselineSignal) + baselineSignal;
                hold(app.group1,"on");
                    app.tempplot = plot(app.group1,app.stagingSignalTimeIdx,app.baseline,'Color','r');
                hold(app.group1,"off");
            end
            app.pretreatmentSignalPointNum = length(app.pretreatmentSignal);
            app.pretreatmentSignalTime = app.pretreatmentSignalPointNum/app.pretreatmentSignalSampleRate;
            app.pretreatmentSignalTimeIdx = (1/app.pretreatmentSignalSampleRate:1/app.pretreatmentSignalSampleRate:app.pretreatmentSignalTime);
            app.pretreatmentSignalFreqIdx = app.pretreatmentSignalSampleRate*(1:ceil(app.pretreatmentSignalPointNum/2+1))/app.pretreatmentSignalPointNum;
            fftSignal = abs(fft(app.pretreatmentSignal)/app.pretreatmentSignalPointNum);
            fftSignal = fftSignal(1:ceil(app.pretreatmentSignalPointNum/2+1));
            app.fftPretreatmentSignal = fftSignal;
            plot(app.group2,app.pretreatmentSignalTimeIdx,app.pretreatmentSignal);
            plot(app.group2fft,app.pretreatmentSignalFreqIdx,fftSignal,'Color','r');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            displayInit(app);
            app.stagingSignal = app.pretreatmentSignal;
            app.stagingSignalPointNum = app.pretreatmentSignalPointNum;
            app.stagingSignalSampleRate = app.pretreatmentSignalSampleRate;
            app.stagingSignalTime = app.pretreatmentSignalTime;
            app.stagingSignalTimeIdx = app.pretreatmentSignalTimeIdx;
            app.stagingSignalFreqIdx = app.pretreatmentSignalFreqIdx;
            app.fftStagingSignal = app.fftPretreatmentSignal;
            app.sampleRate.Text = num2str(app.stagingSignalSampleRate);
            app.sampleInterval.Text = num2str(1e6/app.stagingSignalSampleRate);
            app.sampleTime.Text = num2str(app.stagingSignalTime);
            app.samplePoints.Text = num2str(app.stagingSignalPointNum);
            plot(app.group1,app.stagingSignalTimeIdx,app.stagingSignal);
            plot(app.group1fft,app.stagingSignalFreqIdx,app.fftStagingSignal,'Color','r');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if ~isempty(app.DropDown.Value)
                app.NaNLabel_5.Text = app.DropDown.Value;
            end
            app.dealSignal = app.stagingSignal;
            poly1_order = app.EditField_12.Value;
            poly2_order = app.EditField_15.Value;
            window_size = app.EditField_13.Value;
            [~, app.baseline] = polynomial_baseline_correction(app.dealSignal,window_size,poly1_order,poly2_order);
            app.dealSignalPointNum = app.stagingSignalPointNum;
            app.dealSignalSampleRate = app.stagingSignalSampleRate;
            app.dealSignalTime = app.stagingSignalTime;
            app.dealSignalTimeIdx = app.stagingSignalTimeIdx;
            app.NaNLabel_12.Text = num2str(mean(app.dealSignal));
% 
%                 N = length(app.dealSignal);
%                 t = 1:N;
%                 poly1_order = app.EditField_12.Value;
%                 poly2_order = app.EditField_15.Value;
%                 window_size = app.EditField_13.Value;
%                 [p_global, ~, mu] = polyfit(t, app.dealSignal, poly1_order);
%                 baseline_global = polyval(p_global, t, [], mu);
%                 step_size = ceil(N / window_size);
%                 baseline_local = zeros(N, 1);
%                 for i = 1:step_size
%                     idx_start = (i-1) * window_size + 1;
%                     idx_end = min(i * window_size, N);
%                     idx = idx_start:idx_end;
%                     residual = app.dealSignal(idx) - baseline_global(idx)';
%                     if length(idx) >= poly2_order + 1
%                         [p_local, ~, mu_local] = polyfit(t(idx), residual, poly2_order);
%                         baseline_local(idx) = polyval(p_local, t(idx), [], mu_local);
%                     end
%                 end
%                 total_baseline = baseline_global' + baseline_local;
%                 dealbaseline = total_baseline;

            plot(app.UIAxes6,app.dealSignalTimeIdx,app.dealSignal);
%             hold(app.UIAxes6,"on");
%             plot(app.UIAxes6,app.dealSignalTimeIdx,dealbaseline,'Color','r');
%             hold(app.UIAxes6,"off");
            drawnow;
            app.TabGroup.SelectedTab = app.Tab_6;
        end

        % Menu selected function: Menu_5
        function drawOriginalSignal(app, event)
            cla(app.UIAxes5);drawnow;
            plot(app.UIAxes5,app.originalSignalTimeIdx,app.originalSignal);
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            cla(app.UIAxes5);drawnow;
            plot(app.UIAxes5,app.pretreatmentSignalTimeIdx,app.pretreatmentSignal);
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            cla(app.UIAxes5);drawnow;
            plot(app.UIAxes5,app.originalSignalFreqIdx,app.fftOriginalSignal,'Color','r');
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            cla(app.UIAxes5);drawnow;
            plot(app.UIAxes5,app.pretreatmentSignalFreqIdx,app.fftPretreatmentSignal,'Color','r');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            hold(app.UIAxes6,"on");
            if isgraphics(app.tempplot)
                delete(app.tempplot);
            end
            if ~isempty(app.tempplot1)
                for i=1:length(app.tempplot1)
                    delete(app.tempplot1{i});
                end
            end
            drawnow;
            startCoeff = app.EditField_4.Value;
            endCoeff = app.EditField_5.Value;
            filterCoeff = app.EditField_6.Value;
            RoughEventLocations = recursive_low_pass_VK(app.dealSignal, startCoeff, endCoeff, filterCoeff);
            % delta = app.deltaEditField.Value;
            % sigma = app.sigmaEditField.Value;
            % Normalize = app.NormalizeCheckBox.Value;
            % ImpulsionLimit = app.ImpulsionLimitEditField.Value;
            % BaselineLength = app.BaselineLengthEditField.Value;
            % TooMuchLevels = app.TooMuchLevelsCheckBox.Value;
            % IncludedBaselinePlots = app.IncludeBaselinePlotsEditField.Value;
            % hBook = app.hBookEditField.Value;
            % sd = app.sdEditField.Value;
            % CusumParameters=[delta,sigma,Normalize,ImpulsionLimit,BaselineLength,TooMuchLevels,IncludedBaselinePlots,hBook,sd];
            % [EventDatabase]=event_detection_v2(app.dealSignal, CusumParameters, RoughEventLocations);
            % app.NaNLabel_11.Text = num2str(length(EventDatabase));drawnow;
            % app.eventIdx = cell(0,1);
            % app.eventArray = cell(0,1);
            % app.eventmin = zeros(0,1);
            % eventminidx= zeros(0,1);
            % app.dwellTime = zeros(0,1);
            % app.dwellDeepth = zeros(0,1);
            % app.eventFit=cell(0,1);
            % for i=1:length(EventDatabase)
            %     app.eventIdx{i,1} = EventDatabase{i}.StartAndEndPoint(1) : EventDatabase{i}.StartAndEndPoint(2);
            %     app.dwellTime(i,1) = length(app.eventIdx{i,1})/app.dealSignalSampleRate;
            %     app.eventArray{i,1} = app.dealSignal(app.eventIdx{i,1});
            %     app.eventFit{i,1} = EventDatabase{i}.AllLevelFits;
            %     [app.eventmin(i,1), eventminidx(i,1)] = min(app.eventArray{i,1});
            %     if strcmp(app.DropDown_5.Value,'当前')
            %         app.dwellDeepth(i,1) = abs(mean(app.dealSignal(max(1,EventDatabase{i}.StartAndEndPoint(1)-100):EventDatabase{i}.StartAndEndPoint(1))) - app.eventmin(i,1));
            %     else
            %         app.dwellDeepth(i,1) = abs(mean(app.dealSignal(max(1,EventDatabase{i}.StartAndEndPoint(1)-100):EventDatabase{i}.StartAndEndPoint(1))) - app.eventmin(i,1))*1e12;
            %     end
            % end
            % for i=1:length(app.eventIdx)
            %     scatter(app.UIAxes6,(app.eventIdx{i,1}(1)+eventminidx(i,1)-1)/app.dealSignalSampleRate,app.eventmin(i),'red','filled');
            % end
            % app.tempplot = yline(app.UIAxes6, app.EditField_16.Value,'g','LineWidth',2);
            % hold(app.UIAxes6,"off");
            app.NaNLabel_11.Text = num2str(length(RoughEventLocations));drawnow;
            app.eventIdx = cell(0,1);
            app.eventArray = cell(0,1);
            app.eventmin = zeros(0,1);
            eventminidx= zeros(0,1);
            app.dwellTime = zeros(0,1);
            app.dwellDeepth = zeros(0,1);
            for i=1:length(RoughEventLocations)
                app.eventIdx{i,1} = RoughEventLocations(i,1) : RoughEventLocations(i,2);
                app.dwellTime(i,1) = length(app.eventIdx{i,1})/app.dealSignalSampleRate;
                app.eventArray{i,1} = app.dealSignal(app.eventIdx{i,1});
                [app.eventmin(i,1), eventminidx(i,1)] = min(app.eventArray{i,1});
                if strcmp(app.DropDown_5.Value,'当前')
                    app.dwellDeepth(i,1) = abs(mean(app.dealSignal(max(1,RoughEventLocations(i,1)-100):RoughEventLocations(i,1))) - app.eventmin(i,1));
                    %app.dwellDeepth(i,1) = abs(mean(app.baseline(app.eventIdx{i,1})) - value);
                else
                    app.dwellDeepth(i,1) = abs(mean(app.dealSignal(max(1,RoughEventLocations(i,1)-100):RoughEventLocations(i,1))) - app.eventmin(i,1))*1e12;
                    %app.dwellDeepth(i,1) = abs(mean(app.baseline(app.eventIdx{i,1})) - value)*1e12;
                end
            end
            for i=1:length(app.eventIdx)
                app.tempplot1{i} = scatter(app.UIAxes6,(app.eventIdx{i,1}(1)+eventminidx(i,1)-1)/app.dealSignalSampleRate,app.eventmin(i),'red','filled');
            end
            %app.tempplot = yline(app.UIAxes6, app.EditField_16.Value,'g','LineWidth',2);
            app.tempplot = plot(app.UIAxes6,app.dealSignalTimeIdx,app.baseline,'Color','r');
            hold(app.UIAxes6,"off");
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.pickeventIdx = app.eventIdx;
            app.pickdwellTime = app.dwellTime;
            app.pickdwellDeepth = app.dwellDeepth;
            %app.pickeventFit = app.eventFit;
            picktimeThreshold1 = app.EditField_8.Value/1e6;
            picktimeThreshold2 = app.EditField_9.Value/1e6;
            pickdeepthThreshold1 = app.EditField_10.Value;
            pickdeepthThreshold2 = app.EditField_11.Value;
            if picktimeThreshold2>0 && pickdeepthThreshold2>0
                app.pickdwellTime = app.dwellTime(app.dwellTime(:)>picktimeThreshold1);
                app.pickdwellDeepth = app.dwellDeepth(app.dwellTime(:)>picktimeThreshold1);
                app.pickeventIdx = app.eventIdx(app.dwellTime(:)>picktimeThreshold1);
                %app.pickeventFit = app.eventFit(app.dwellTime(:)>picktimeThreshold1);
                app.pickdwellTime = app.pickdwellTime(app.pickdwellTime(:)<picktimeThreshold2);
                app.pickdwellDeepth = app.pickdwellDeepth(app.pickdwellTime(:)<picktimeThreshold2);
                app.pickeventIdx = app.pickeventIdx(app.pickdwellTime(:)<picktimeThreshold2);
                %app.pickeventFit = app.pickeventFit(app.pickdwellTime(:)<picktimeThreshold2);

                app.pickdwellDeepth = app.pickdwellDeepth(app.pickdwellDeepth(:)>pickdeepthThreshold1);
                app.pickdwellTime = app.pickdwellTime(app.pickdwellDeepth(:)>pickdeepthThreshold1);
                app.pickeventIdx = app.pickeventIdx(app.pickdwellDeepth(:)>pickdeepthThreshold1);
                %app.pickeventFit = app.pickeventFit(app.pickdwellDeepth(:)>pickdeepthThreshold1);
                app.pickdwellDeepth = app.pickdwellDeepth(app.pickdwellDeepth(:)<pickdeepthThreshold2);
                app.pickdwellTime = app.pickdwellTime(app.pickdwellDeepth(:)<pickdeepthThreshold2);
                app.pickeventIdx = app.pickeventIdx(app.pickdwellDeepth(:)<pickdeepthThreshold2);
                %app.pickeventFit = app.pickeventFit(app.pickdwellDeepth(:)<pickdeepthThreshold2);
            end
            % disp(picktimeThreshold1);
            % disp(picktimeThreshold2);
            % disp(size(app.eventIdx));
            % disp(size(app.dwellTime));
            % disp(size(app.dwellDeepth));
            % disp(size(app.pickeventIdx));
            % disp(size(app.pickdwellTime));
            % disp(size(app.pickdwellDeepth));
            app.Spinner_2.Value=1;
            app.Spinner_2ValueChanged(app.Spinner_2);
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            for i=1:9
                cla(app.plotEventFigure{i});
                 app.plotEventFigure{i}.Title.String='';
            end
            eventPage=ceil(length(app.pickeventIdx)/9);
            app.NaNNaNLabel.Text = sprintf('%d个事件，共%d页',length(app.pickeventIdx), eventPage);
            if eventPage>1
                app.Spinner_2.Enable = "on";
                app.Spinner_2.Limits=[1,eventPage];
            else
                app.Spinner_2.Enable = "off";
                app.Spinner_2.Value = 1;
            end
            pageEventStart=(app.Spinner_2.Value-1)*9;
            for i=1:min(9,length(app.pickeventIdx)-pageEventStart)
                hold(app.plotEventFigure{i},"on");
                idx=pageEventStart+i;
                addWidth=ceil(length(app.pickeventIdx{idx})*app.EditField_7.Value);
                start=app.pickeventIdx{idx}(1)-addWidth;
                end_=app.pickeventIdx{idx}(end)+addWidth;
                if start<1
                    start=1;
                end
                if end_>length(app.dealSignal)
                    end_=length(app.dealSignal);
                end
                if app.CheckBox.Value
                    plot(app.plotEventFigure{i},start:end_,app.dealSignal(start:end_).*(-1));
                    %plot(app.plotEventFigure{i},app.pickeventIdx{idx}, app.pickeventFit{idx}.*(-1),'Color','g','LineWidth',2);
                    %plot(app.plotEventFigure{i},start:end_,app.baseline(start:end_).*(-1),'Color','y','LineWidth',2);
                    if app.CheckBox_2.Value
                        %yline(app.plotEventFigure{i}, app.EditField_16.Value*(-1),'g','LineWidth',2);
%                         sorted_A = sort(app.dealSignal(start:end_), 'ascend');
%                         num = floor(app.EditField_8.Value*app.dealSignalSampleRate*1e-6);
%                         smallest = sorted_A(1:num);
%                         mean_smallest = mean(smallest);
                        %med = median(app.dealSignal(start:end_));
                        ttt = ceil((app.pickeventIdx{idx}(1)+app.pickeventIdx{idx}(end))/2);
                        value = mean(app.dealSignal(ttt-100:ttt+100));
                        yline(app.plotEventFigure{i}, min(app.dealSignal(start:end_))*(-1),'k','LineWidth',2);
                        yline(app.plotEventFigure{i}, mean(app.dealSignal(max(1,app.pickeventIdx{idx}(1)-100):app.pickeventIdx{idx}(1)))*(-1),'y','LineWidth',2);
                    end
                else
                    plot(app.plotEventFigure{i},start:end_,app.dealSignal(start:end_));
                    plot(app.plotEventFigure{i},start:end_,app.baseline(start:end_));
                    if app.CheckBox_2.Value
                        %yline(app.plotEventFigure{i}, app.EditField_16.Value,'g','LineWidth',2);
                        yline(app.plotEventFigure{i}, mean(app.dealSignal(app.pickeventIdx{idx}(1):app.pickeventIdx{idx}(end))),'k','LineWidth',2);
                        yline(app.plotEventFigure{i}, mean(app.dealSignal(app.pickeventIdx{idx}(1):app.pickeventIdx{idx}(1)+20)),'y','LineWidth',2);
                    end
                end
                if app.CheckBox_5.Value
                    axis(app.plotEventFigure{i}, 'tight');
                    axis(app.plotEventFigure{i}, 'tight');
                else
                    axis(app.plotEventFigure{i}, 'normal');
                    axis(app.plotEventFigure{i}, 'normal');
                end
                if app.CheckBox_4.Value
                    app.plotEventFigure{i}.Title.String=sprintf('序号：%d，阻塞时间：%g us，阻塞深度：%g pA', idx,app.pickdwellTime(idx)*1e6,app.pickdwellDeepth(idx));
                else
                    app.plotEventFigure{i}.Title.String=sprintf('序号：%d', idx);
                end
                if app.CheckBox_3.Value
                    xline(app.plotEventFigure{i},app.pickeventIdx{idx}(1),'r--');
                    xline(app.plotEventFigure{i},app.pickeventIdx{idx}(end),'r--');
                end
                hold(app.plotEventFigure{i},"off");
            end
        end

        % Menu selected function: Menu_32
        function Menu_32Selected(app, event)
            defaultFileName = [app.DefaultParameterFileName app.ParameterFileExtension];
            ff = figure("Name",'fuben','Position',[-120 -200 100 10]);
            [file, path] = uiputfile({'*.txt', 'Text files (*.txt)'},'保存参数到文件',defaultFileName);
            delete(ff);
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            fullPath = fullfile(path, file);
            try
                % 打开文件用于写入
                fid = fopen(fullPath, 'w');

                fprintf(fid, '%s = %s\n', app.EditField.Tag, num2str(app.EditField.Value));
                fprintf(fid, '%s = %s\n', app.Spinner.Tag, num2str(app.Spinner.Value));
                fprintf(fid, '%s = %s\n', app.DropDown_2.Tag, app.DropDown_2.Value);
                fprintf(fid, '%s = %s\n', app.EditField_14.Tag, num2str(app.EditField_14.Value));
                fprintf(fid, '%s = %s\n', app.EditField_12.Tag, num2str(app.EditField_12.Value));
                fprintf(fid, '%s = %s\n', app.EditField_13.Tag, num2str(app.EditField_13.Value));
                fprintf(fid, '%s = %s\n', app.EditField_15.Tag, num2str(app.EditField_15.Value));
                fprintf(fid, '%s = %s\n', app.EditField_3.Tag, num2str(app.EditField_3.Value));
                fprintf(fid, '%s = %s\n', app.group1fftEditField.Tag, num2str(app.group1fftEditField.Value));
                fprintf(fid, '%s = %s\n', app.group2fftEditField.Tag, num2str(app.group2fftEditField.Value));
                fprintf(fid, '%s = %s\n', app.EditField_4.Tag, num2str(app.EditField_4.Value));
                fprintf(fid, '%s = %s\n', app.EditField_5.Tag, num2str(app.EditField_5.Value));
                fprintf(fid, '%s = %s\n', app.EditField_6.Tag, num2str(app.EditField_6.Value));
                fprintf(fid, '%s = %s\n', app.deltaEditField.Tag, num2str(app.deltaEditField.Value));
                fprintf(fid, '%s = %s\n', app.sigmaEditField.Tag, num2str(app.sigmaEditField.Value));
                fprintf(fid, '%s = %s\n', app.ImpulsionLimitEditField.Tag, num2str(app.ImpulsionLimitEditField.Value));
                fprintf(fid, '%s = %s\n', app.BaselineLengthEditField.Tag, num2str(app.BaselineLengthEditField.Value));
                fprintf(fid, '%s = %s\n', app.IncludeBaselinePlotsEditField.Tag, num2str(app.IncludeBaselinePlotsEditField.Value));
                fprintf(fid, '%s = %s\n', app.hBookEditField.Tag, num2str(app.hBookEditField.Value));
                fprintf(fid, '%s = %s\n', app.sdEditField.Tag, num2str(app.sdEditField.Value));
                fprintf(fid, '%s = %s\n', app.NormalizeCheckBox.Tag, num2str(app.NormalizeCheckBox.Value));
                fprintf(fid, '%s = %s\n', app.TooMuchLevelsCheckBox.Tag, num2str(app.TooMuchLevelsCheckBox.Value));
                fprintf(fid, '%s = %s\n', app.EditField_16.Tag, num2str(app.EditField_16.Value));
                fprintf(fid, '%s = %s\n', app.CheckBox.Tag, num2str(app.CheckBox.Value));
                fprintf(fid, '%s = %s\n', app.EditField_7.Tag, num2str(app.EditField_7.Value));
                fprintf(fid, '%s = %s\n', app.CheckBox_2.Tag, num2str(app.CheckBox_2.Value));
                fprintf(fid, '%s = %s\n', app.CheckBox_3.Tag, num2str(app.CheckBox_3.Value));
                fprintf(fid, '%s = %s\n', app.CheckBox_4.Tag, num2str(app.CheckBox_4.Value));
                fprintf(fid, '%s = %s\n', app.CheckBox_5.Tag, num2str(app.CheckBox_5.Value));
                fprintf(fid, '%s = %s\n', app.EditField_8.Tag, num2str(app.EditField_8.Value));
                fprintf(fid, '%s = %s\n', app.EditField_9.Tag, num2str(app.EditField_9.Value));
                fprintf(fid, '%s = %s\n', app.EditField_10.Tag, num2str(app.EditField_10.Value));
                fprintf(fid, '%s = %s\n', app.EditField_11.Tag, num2str(app.EditField_11.Value));
                fprintf(fid, '%s = %s\n', app.EditField_17.Tag, num2str(app.EditField_17.Value));
                fprintf(fid, '%s = %s\n', app.Spinner_3.Tag, num2str(app.Spinner_3.Value));
                fprintf(fid, '%s = %s\n', app.DropDown_3.Tag, num2str(app.DropDown_3.Value));
                fprintf(fid, '%s = %s\n', app.EditField_18.Tag, num2str(app.EditField_18.Value));
                fprintf(fid, '%s = %s\n', app.Spinner_4.Tag, num2str(app.Spinner_4.Value));
                fprintf(fid, '%s = %s\n', app.DropDown_4.Tag, num2str(app.DropDown_4.Value));

                fclose(fid);
                uialert(app.UIFigure, '参数保存成功!', '成功');
            catch ME
                uialert(app.UIFigure, ['保存参数时出错: ' ME.message], '错误');
                if exist('fid', 'var') && fid ~= -1
                    fclose(fid);
                end
            end
        end

        % Menu selected function: Menu_33
        function Menu_33Selected(app, event)
            ff = figure("Name",'fuben','Position',[-120 -200 100 10]);
            [file, path] = uigetfile({'*.txt', 'Text files (*.txt)'},'从文件加载参数');
            delete(ff);
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            fullPath = fullfile(path, file);
            try
                fileContent = fileread(fullPath);
                lines = strsplit(fileContent, '\n');
                for i = 1:length(lines)
                    line = strtrim(lines{i});
                    if isempty(line)
                        continue;
                    end
                    parts = strsplit(line, '=');
                    if length(parts) == 2
                        fieldName = strtrim(parts{1});
                        if ~strcmp(fieldName,app.DropDown_2.Tag)
                            fieldValue = str2double(strtrim(parts{2}));
                        else
                            fieldValue = strtrim(parts{2});
                        end
                        try
                            component = findobj(app.UIFigure, 'Tag', fieldName);
                            if ~isempty(component)
                                component.Value = fieldValue;
                            end
                        catch
                        end
                    end
                end
                uialert(app.UIFigure, '参数加载成功!', '成功');
            catch ME
                uialert(app.UIFigure, ['加载参数时出错: ' ME.message], '错误');
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.holdonCheckBox.Value
                hold(app.UIAxes12,'on');
            else
                hold(app.UIAxes12,'off');
            end
            scatter(app.UIAxes12, app.pickdwellTime.*1e3, app.pickdwellDeepth,'filled');
            % plot_custom_histogram(app.pickdwellDeepth,'Parent',app.UIAxes11,'NumBins',80,'GapRatio',0.4,'AddKDE',1,'Orientation', 'horizontal','Padding',0.3);
            % plot_custom_histogram(app.pickdwellTime,'Parent',app.UIAxes13,'NumBins',80,'GapRatio',0.4,'AddKDE',1,'Padding',0.3);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Button_9.Enable = 'off';drawnow;
            fileNum = length(app.ListBox.Value);
            scatterData = zeros(0,2);
            for fileIdx=1:fileNum
                [dataMessage]=readFileData(fullfile(app.filePath,app.ListBox.Value{fileIdx}));
                rawSignal = dataMessage.signalData;
                fs = dataMessage.sampleRate;
                N = dataMessage.signalPointNum;
                poly1_order = app.EditField_12.Value;
                poly2_order = app.EditField_15.Value;
                window_size = app.EditField_13.Value;
                [preSignal,~] = polynomial_baseline_correction(rawSignal,window_size,poly1_order,poly2_order);
                cutoff = app.EditField_17.Value;
                pole = app.Spinner_3.Value;
                type = app.DropDown_3.Value;
                stagSignal = butterfilt(preSignal,cutoff,pole,fs,'type',type);
                startCoeff = app.EditField_4.Value;
                endCoeff = app.EditField_5.Value;
                filterCoeff = app.EditField_6.Value;
                RoughEventLocations = recursive_low_pass_VK(stagSignal, startCoeff, endCoeff, filterCoeff);
                eventMin=zeros(0,1);
                DwellTime=zeros(0,1);
                DwellDeepth=zeros(0,1);
                eventMean = zeros(0,1);
                EventIdx=cell(0,1);
                event = cell(0,1);
                for i=1:length(RoughEventLocations)
                    EventIdx{i,1} = RoughEventLocations(i,1) : RoughEventLocations(i,2);
                    event{i,1} = stagSignal(EventIdx{i,1});
                    [eventMin(i,1), eventMinidx(i,1)] = min(event{i,1});
                    eventMean(i,1) = mean(event{i,1});
                    DwellTime(i,1) = 1e6*length(EventIdx{i,1})/fs;
                    if strcmp(app.DropDown_5.Value,'当前')
                        DwellDeepth(i,1) = abs(mean(stagSignal(max(1,RoughEventLocations(i,1)-100):RoughEventLocations(i,1))) - eventMin(i,1));
                        %DwellDeepth(i,1) = abs(mean(Baseline(EventIdx{i,1})) - value);
                    else
                        DwellDeepth(i,1) = abs(mean(stagSignal(max(1,RoughEventLocations(i,1)-100):RoughEventLocations(i,1))) - eventMin(i,1))*1e12;
                        %DwellDeepth(i,1) = abs(mean(Baseline(EventIdx{i,1})) - value)*1e12;
                    end
                end
                timeThreshold = [app.EditField_8.Value,app.EditField_9.Value];
                deepthThreshold = [app.EditField_10.Value,app.EditField_11.Value];
                pickTime = find(DwellTime >= timeThreshold(1) & DwellTime <= timeThreshold(2));
                pickDeepth = find(DwellDeepth >= deepthThreshold(1) & DwellDeepth <= deepthThreshold(2));
                pickIdx = pickTime(ismember(pickTime, pickDeepth));
                eventNum = length(pickIdx);
                if eventNum
                    pickEventTime = DwellTime(pickIdx);
                    pickEventDeepth = DwellDeepth(pickIdx);
                    % pickEventData = event{pickIdx};
                    % pickEventIdx = EventIdx{pickIdx};
                    scatterData(end+1 : end+eventNum,:) = [pickEventTime,pickEventDeepth];
                end
            end
            if app.holdonCheckBox_2.Value
                hold(app.UIAxes14,'on');
            else
                hold(app.UIAxes14,'off');
            end
            scatter(app.UIAxes14,scatterData(:,1),scatterData(:,2),'filled');drawnow;
            app.Button_9.Enable = 'on';drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '信号';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '概览';
            app.GridLayout3 = uigridlayout(app.Tab_2);
            app.group1 = uiaxes(app.GridLayout3);
            title(app.group1, 'group-1')
            xlabel(app.group1, 'X')
            ylabel(app.group1, 'Y')
            zlabel(app.group1, 'Z')
            app.group1fft = uiaxes(app.GridLayout3);
            title(app.group1fft, 'group-1-fft')
            xlabel(app.group1fft, 'X')
            ylabel(app.group1fft, 'Y')
            zlabel(app.group1fft, 'Z')
            app.group2 = uiaxes(app.GridLayout3);
            title(app.group2, 'group-2')
            xlabel(app.group2, 'X')
            ylabel(app.group2, 'Y')
            zlabel(app.group2, 'Z')
            app.group2fft = uiaxes(app.GridLayout3);
            title(app.group2fft, 'group-2-fft')
            xlabel(app.group2fft, 'X')
            ylabel(app.group2fft, 'Y')
            zlabel(app.group2fft, 'Z')
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '信号查看';
            app.GridLayout4 = uigridlayout(app.Tab_3);
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Panel = uipanel(app.GridLayout2);
            app.Panel.Title = '操作面板';
            app.GridLayout5 = uigridlayout(app.Panel);
            app.group1Panel = uipanel(app.GridLayout5);
            app.group1Panel.Title = 'group-1信号参数';
            app.GridLayout6 = uigridlayout(app.group1Panel);
            app.Label = uilabel(app.GridLayout6);
            app.Label.Text = '采样率';
            app.sampleRate = uilabel(app.GridLayout6);
            app.sampleRate.Text = 'NaN';
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Text = '采样间隔';
            app.sampleInterval = uilabel(app.GridLayout6);
            app.sampleInterval.Text = 'NaN';
            app.Label_3 = uilabel(app.GridLayout6);
            app.Label_3.Text = '采样时间';
            app.sampleTime = uilabel(app.GridLayout6);
            app.sampleTime.Text = 'NaN';
            app.Label_4 = uilabel(app.GridLayout6);
            app.Label_4.Text = '数据点数';
            app.samplePoints = uilabel(app.GridLayout6);
            app.samplePoints.Text = 'NaN';
            app.HzLabel = uilabel(app.GridLayout6);
            app.HzLabel.Text = 'Hz';
            app.usLabel = uilabel(app.GridLayout6);
            app.usLabel.Text = 'us';
            app.sLabel = uilabel(app.GridLayout6);
            app.sLabel.Text = 's';
            app.pointsLabel = uilabel(app.GridLayout6);
            app.pointsLabel.Text = 'points';
            app.TabGroup3 = uitabgroup(app.GridLayout5);
            app.Tab_9 = uitab(app.TabGroup3);
            app.Tab_9.Title = '多项式拟合';
            app.GridLayout23 = uigridlayout(app.Tab_9);
            app.Label_24 = uilabel(app.GridLayout23);
            app.Label_24.Text = '局部多项式阶数';
            app.EditField_15 = uieditfield(app.GridLayout23, 'numeric');
            app.EditField_15.Tag = 'fx_pole2';
            app.EditField_15.Value = 5;
            app.Label_21 = uilabel(app.GridLayout23);
            app.Label_21.Text = '全局多项式阶数';
            app.EditField_12 = uieditfield(app.GridLayout23, 'numeric');
            app.EditField_12.Tag = 'fx_pole1';
            app.EditField_12.Value = 2;
            app.Label_22 = uilabel(app.GridLayout23);
            app.Label_22.Text = '拟合窗大小';
            app.EditField_13 = uieditfield(app.GridLayout23, 'numeric');
            app.EditField_13.ValueDisplayFormat = '%.0f';
            app.EditField_13.Tag = 'fx_window';
            app.EditField_13.Value = 30000;
            app.Tab_14 = uitab(app.TabGroup3);
            app.Tab_14.Title = '巴特沃斯滤波';
            app.GridLayout26 = uigridlayout(app.Tab_14);
            app.Label_31 = uilabel(app.GridLayout26);
            app.Label_31.Text = '截止频率';
            app.EditField_17 = uieditfield(app.GridLayout26, 'numeric');
            app.EditField_17.ValueDisplayFormat = '%.0f';
            app.EditField_17.Tag = 'butter_cutoff';
            app.EditField_17.Value = 10000;
            app.Label_32 = uilabel(app.GridLayout26);
            app.Label_32.Text = '阶数';
            app.Spinner_3 = uispinner(app.GridLayout26);
            app.Spinner_3.Tag = 'butter_pole';
            app.Spinner_3.Value = 8;
            app.Label_33 = uilabel(app.GridLayout26);
            app.Label_33.Text = '类型';
            app.DropDown_3 = uidropdown(app.GridLayout26);
            app.DropDown_3.Items = {'low', 'high'};
            app.DropDown_3.Tag = 'butter_type';
            app.DropDown_3.Value = 'low';
            app.Tab_4 = uitab(app.TabGroup3);
            app.Tab_4.Title = '贝塞尔滤波';
            app.GridLayout8 = uigridlayout(app.Tab_4);
            app.Label_5 = uilabel(app.GridLayout8);
            app.Label_5.Text = '截止频率';
            app.EditField = uieditfield(app.GridLayout8, 'numeric');
            app.EditField.ValueDisplayFormat = '%.3f';
            app.EditField.Tag = 'bess_cutoff';
            app.EditField.Value = 30000;
            app.Label_6 = uilabel(app.GridLayout8);
            app.Label_6.Text = '类型';
            app.DropDown_2 = uidropdown(app.GridLayout8);
            app.DropDown_2.Items = {'low', 'high'};
            app.DropDown_2.Tag = 'bess_type';
            app.DropDown_2.Value = 'low';
            app.Label_8 = uilabel(app.GridLayout8);
            app.Label_8.Text = '阶数';
            app.Spinner = uispinner(app.GridLayout8);
            app.Spinner.Limits = [2 10];
            app.Spinner.Tag = 'bess_pole';
            app.Spinner.Value = 2;
            app.Tab_13 = uitab(app.TabGroup3);
            app.Tab_13.Title = '重采样';
            app.GridLayout24 = uigridlayout(app.Tab_13);
            app.Label_23 = uilabel(app.GridLayout24);
            app.Label_23.Text = '目标采样频率';
            app.EditField_14 = uieditfield(app.GridLayout24, 'numeric');
            app.EditField_14.ValueDisplayFormat = '%.0f';
            app.EditField_14.Tag = 'resample_fs';
            app.EditField_14.Value = 100000;
            app.Label_25 = uilabel(app.GridLayout24);
            app.Label_25.Text = '采样率';
            app.NaNLabel_7 = uilabel(app.GridLayout24);
            app.NaNLabel_7.Text = 'NaN';
            app.HzLabel_2 = uilabel(app.GridLayout24);
            app.HzLabel_2.Text = 'Hz';
            app.Label_26 = uilabel(app.GridLayout24);
            app.Label_26.Text = '采样间隔';
            app.NaNLabel_8 = uilabel(app.GridLayout24);
            app.NaNLabel_8.Text = 'NaN';
            app.usLabel_2 = uilabel(app.GridLayout24);
            app.usLabel_2.Text = 'us';
            app.Label_27 = uilabel(app.GridLayout24);
            app.Label_27.Text = '采样时间';
            app.NaNLabel_9 = uilabel(app.GridLayout24);
            app.NaNLabel_9.Text = 'NaN';
            app.sLabel_2 = uilabel(app.GridLayout24);
            app.sLabel_2.Text = 's';
            app.Label_28 = uilabel(app.GridLayout24);
            app.Label_28.Text = '数据点数';
            app.NaNLabel_10 = uilabel(app.GridLayout24);
            app.NaNLabel_10.Text = 'NaN';
            app.pointsLabel_2 = uilabel(app.GridLayout24);
            app.pointsLabel_2.Text = 'points';
            app.Tab_5 = uitab(app.TabGroup3);
            app.Tab_5.Title = '移动平均';
            app.GridLayout9 = uigridlayout(app.Tab_5);
            app.Label_7 = uilabel(app.GridLayout9);
            app.Label_7.Text = '移动窗大小';
            app.EditField_3 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.EditField_3.Tag = 'movmean_window';
            app.EditField_3.Value = 10000;
            app.Tab_15 = uitab(app.TabGroup3);
            app.Tab_15.Title = '切比雪夫滤波';
            app.GridLayout27 = uigridlayout(app.Tab_15);
            app.Label_34 = uilabel(app.GridLayout27);
            app.Label_34.Text = '截止频率';
            app.EditField_18 = uieditfield(app.GridLayout27, 'numeric');
            app.EditField_18.ValueDisplayFormat = '%.0f';
            app.EditField_18.Tag = 'cheby_cutoff';
            app.EditField_18.Value = 10000;
            app.Label_35 = uilabel(app.GridLayout27);
            app.Label_35.Text = '阶数';
            app.Spinner_4 = uispinner(app.GridLayout27);
            app.Spinner_4.Tag = 'cheby_pole';
            app.Spinner_4.Value = 8;
            app.Label_36 = uilabel(app.GridLayout27);
            app.Label_36.Text = '类型';
            app.DropDown_4 = uidropdown(app.GridLayout27);
            app.DropDown_4.Items = {'low', 'high'};
            app.DropDown_4.Tag = 'cheby_type';
            app.DropDown_4.Value = 'low';
            app.readOriginalSignal = uibutton(app.GridLayout5, 'push');
            app.readOriginalSignal.ButtonPushedFcn = createCallbackFcn(app, @readOriginalSignalPushed, true);
            app.readOriginalSignal.Text = '读取原始信号';
            app.Button_3 = uibutton(app.GridLayout5, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '上传';
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '暂存';
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @pretreatment, true);
            app.Button.Text = '处理';
            app.group1fftEditFieldLabel = uilabel(app.GridLayout5);
            app.group1fftEditFieldLabel.Text = 'group1fft最大值';
            app.group1fftEditField = uieditfield(app.GridLayout5, 'numeric');
            app.group1fftEditField.Tag = 'g1fmax';
            app.group2fftEditFieldLabel = uilabel(app.GridLayout5);
            app.group2fftEditFieldLabel.Text = 'group2fft最大值';
            app.group2fftEditField = uieditfield(app.GridLayout5, 'numeric');
            app.group2fftEditField.Tag = 'g2fmax';
            app.Button_7 = uibutton(app.GridLayout5, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @fftyTickMaxSize, true);
            app.Button_7.Text = '更新';
            app.xEditFieldLabel = uilabel(app.GridLayout5);
            app.xEditFieldLabel.Text = '信号查看x轴最大值';
            app.xEditField = uieditfield(app.GridLayout5, 'numeric');
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '事件';
            app.GridLayout10 = uigridlayout(app.Tab_6);
            app.TabGroup4 = uitabgroup(app.GridLayout10);
            app.Tab_7 = uitab(app.TabGroup4);
            app.Tab_7.Title = '检测信号';
            app.GridLayout11 = uigridlayout(app.Tab_7);
            app.UIAxes6 = uiaxes(app.GridLayout11);
            xlabel(app.UIAxes6, '时间(s)')
            ylabel(app.UIAxes6, '电流(pA)')
            zlabel(app.UIAxes6, 'Z')
            app.Panel_3 = uipanel(app.GridLayout11);
            app.Panel_3.Title = '事件检测参数';
            app.GridLayout12 = uigridlayout(app.Panel_3);
            app.Panel_4 = uipanel(app.GridLayout12);
            app.Panel_4.Title = '一次检测';
            app.GridLayout13 = uigridlayout(app.Panel_4);
            app.Label_10 = uilabel(app.GridLayout13);
            app.Label_10.Text = '起始阈值';
            app.EditField_4 = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%.3f';
            app.EditField_4.Tag = 'astart';
            app.EditField_4.Value = 5.3;
            app.Label_11 = uilabel(app.GridLayout13);
            app.Label_11.Text = '结束阈值';
            app.EditField_5 = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%.3f';
            app.EditField_5.Tag = 'aend';
            app.EditField_5.Value = 0.3;
            app.Label_12 = uilabel(app.GridLayout13);
            app.Label_12.Text = '滤波阈值';
            app.EditField_6 = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%.6f';
            app.EditField_6.Tag = 'afilter';
            app.EditField_6.Value = 0.99999;
            app.Panel_5 = uipanel(app.GridLayout12);
            app.Panel_5.Title = '二次检测';
            app.GridLayout14 = uigridlayout(app.Panel_5);
            app.deltaEditFieldLabel = uilabel(app.GridLayout14);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.GridLayout14, 'numeric');
            app.deltaEditField.Tag = 'bdelta';
            app.deltaEditField.Value = 100;
            app.sigmaEditFieldLabel = uilabel(app.GridLayout14);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.GridLayout14, 'numeric');
            app.sigmaEditField.Tag = 'bsigma';
            app.sigmaEditField.Value = 25;
            app.ImpulsionLimitEditFieldLabel = uilabel(app.GridLayout14);
            app.ImpulsionLimitEditFieldLabel.Text = 'ImpulsionLimit';
            app.ImpulsionLimitEditField = uieditfield(app.GridLayout14, 'numeric');
            app.ImpulsionLimitEditField.Tag = 'bImpulsionLimit';
            app.ImpulsionLimitEditField.Value = 50;
            app.BaselineLengthEditFieldLabel = uilabel(app.GridLayout14);
            app.BaselineLengthEditFieldLabel.Text = 'BaselineLength';
            app.BaselineLengthEditField = uieditfield(app.GridLayout14, 'numeric');
            app.BaselineLengthEditField.Tag = 'bBaselineLength';
            app.BaselineLengthEditField.Value = 200;
            app.IncludeBaselinePlotsEditFieldLabel = uilabel(app.GridLayout14);
            app.IncludeBaselinePlotsEditFieldLabel.Text = 'IncludeBaselinePlots';
            app.IncludeBaselinePlotsEditField = uieditfield(app.GridLayout14, 'numeric');
            app.IncludeBaselinePlotsEditField.Tag = 'bIncludeBaselinePlots';
            app.IncludeBaselinePlotsEditField.Value = 150;
            app.hBookEditFieldLabel = uilabel(app.GridLayout14);
            app.hBookEditFieldLabel.Text = 'hBook';
            app.hBookEditField = uieditfield(app.GridLayout14, 'numeric');
            app.hBookEditField.Tag = 'bhBook';
            app.hBookEditField.Value = 0.1;
            app.sdEditFieldLabel = uilabel(app.GridLayout14);
            app.sdEditFieldLabel.Text = 'sd';
            app.sdEditField = uieditfield(app.GridLayout14, 'numeric');
            app.sdEditField.Tag = 'bsd';
            app.sdEditField.Value = 9;
            app.NormalizeCheckBox = uicheckbox(app.GridLayout14);
            app.NormalizeCheckBox.Tag = 'bnormalize';
            app.NormalizeCheckBox.Text = 'Normalize';
            app.TooMuchLevelsCheckBox = uicheckbox(app.GridLayout14);
            app.TooMuchLevelsCheckBox.Tag = 'bTooMuchLevels';
            app.TooMuchLevelsCheckBox.Text = 'TooMuchLevels';
            app.TooMuchLevelsCheckBox.Value = true;
            app.Button_4 = uibutton(app.GridLayout12, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '检测';
            app.Label_18 = uilabel(app.GridLayout12);
            app.Label_18.Text = '事件起始阈值';
            app.EditField_16 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_16.Tag = 'eventstart';
            app.Label_29 = uilabel(app.GridLayout12);
            app.Label_29.Text = '检出事件数：';
            app.NaNLabel_11 = uilabel(app.GridLayout12);
            app.NaNLabel_11.Text = 'NaN';
            app.Label_30 = uilabel(app.GridLayout12);
            app.Label_30.Text = '处理信号均值：';
            app.NaNLabel_12 = uilabel(app.GridLayout12);
            app.NaNLabel_12.Text = 'NaN';
            app.Tab_8 = uitab(app.TabGroup4);
            app.Tab_8.Title = '查看事件';
            app.GridLayout15 = uigridlayout(app.Tab_8);
            app.TabGroup5 = uitabgroup(app.GridLayout15);
            app.Tab_10 = uitab(app.TabGroup5);
            app.Tab_10.Title = '事件图';
            app.GridLayout19 = uigridlayout(app.Tab_10);
            app.UIAxes8 = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes8, '时间(us)')
            ylabel(app.UIAxes8, '电流(pA)')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8_2 = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes8_2, '时间(us)')
            ylabel(app.UIAxes8_2, '电流(pA)')
            zlabel(app.UIAxes8_2, 'Z')
            app.UIAxes8_3 = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes8_3, '时间(us)')
            ylabel(app.UIAxes8_3, '电流(pA)')
            zlabel(app.UIAxes8_3, 'Z')
            app.UIAxes8_4 = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes8_4, '时间(us)')
            ylabel(app.UIAxes8_4, '电流(pA)')
            zlabel(app.UIAxes8_4, 'Z')
            app.UIAxes8_5 = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes8_5, '时间(us)')
            ylabel(app.UIAxes8_5, '电流(pA)')
            zlabel(app.UIAxes8_5, 'Z')
            app.UIAxes8_6 = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes8_6, '时间(us)')
            ylabel(app.UIAxes8_6, '电流(pA)')
            zlabel(app.UIAxes8_6, 'Z')
            app.UIAxes8_7 = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes8_7, '时间(us)')
            ylabel(app.UIAxes8_7, '电流(pA)')
            zlabel(app.UIAxes8_7, 'Z')
            app.UIAxes8_9 = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes8_9, '时间(us)')
            ylabel(app.UIAxes8_9, '电流(pA)')
            zlabel(app.UIAxes8_9, 'Z')
            app.UIAxes8_8 = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes8_8, '时间(us)')
            ylabel(app.UIAxes8_8, '电流(pA)')
            zlabel(app.UIAxes8_8, 'Z')
            app.Panel3 = uipanel(app.GridLayout19);
            app.Panel3.Title = 'Panel3';
            app.GridLayout16 = uigridlayout(app.Panel3);
            app.Panel_6 = uipanel(app.GridLayout16);
            app.Panel_6.Title = '调整';
            app.GridLayout17 = uigridlayout(app.Panel_6);
            app.CheckBox = uicheckbox(app.GridLayout17);
            app.CheckBox.Tag = 'fanzhuan';
            app.CheckBox.Text = '翻转';
            app.CheckBox.Value = true;
            app.Label_14 = uilabel(app.GridLayout17);
            app.Label_14.Text = '扩展宽度比例';
            app.EditField_7 = uieditfield(app.GridLayout17, 'numeric');
            app.EditField_7.Tag = 'addwidthsize';
            app.EditField_7.Value = 1;
            app.CheckBox_2 = uicheckbox(app.GridLayout17);
            app.CheckBox_2.Tag = 'showstart';
            app.CheckBox_2.Text = '显示事件起始阈值';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.GridLayout17);
            app.CheckBox_3.Tag = 'markeventrange';
            app.CheckBox_3.Text = '标记事件范围';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.GridLayout17);
            app.CheckBox_4.Tag = 'showdwell';
            app.CheckBox_4.Text = '显示阻塞参数';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.GridLayout17);
            app.CheckBox_5.Tag = 'tightsignal';
            app.CheckBox_5.Text = '信号紧凑显示';
            app.CheckBox_5.Value = true;
            app.Label_15 = uilabel(app.GridLayout16);
            app.Label_15.Text = '当前页码：';
            app.Spinner_2 = uispinner(app.GridLayout16);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 1;
            app.Panel_7 = uipanel(app.GridLayout16);
            app.Panel_7.Title = '筛选';
            app.GridLayout18 = uigridlayout(app.Panel_7);
            app.Label_19 = uilabel(app.GridLayout18);
            app.Label_19.Text = '< 阻塞时间 <';
            app.EditField_8 = uieditfield(app.GridLayout18, 'numeric');
            app.EditField_8.Tag = 'dwelltimemin';
            app.EditField_9 = uieditfield(app.GridLayout18, 'numeric');
            app.EditField_9.Tag = 'dwelltimemax';
            app.EditField_10 = uieditfield(app.GridLayout18, 'numeric');
            app.EditField_10.Tag = 'dwelldeepthmin';
            app.EditField_11 = uieditfield(app.GridLayout18, 'numeric');
            app.EditField_11.Tag = 'dwelldeepthmax';
            app.Label_17 = uilabel(app.GridLayout18);
            app.Label_17.Text = '< 阻塞深度 <';
            app.NaNNaNLabel = uilabel(app.GridLayout16);
            app.NaNNaNLabel.Text = 'NaN个事件，共NaN页';
            app.Button_6 = uibutton(app.GridLayout16, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '查看事件';
            app.Label_37 = uilabel(app.GridLayout16);
            app.Label_37.Text = '阻塞深度数量级';
            app.DropDown_5 = uidropdown(app.GridLayout16);
            app.DropDown_5.Items = {'当前', '12'};
            app.DropDown_5.Value = '当前';
            app.Tab_12 = uitab(app.TabGroup5);
            app.Tab_12.Title = '阻塞时间-深度分布';
            app.GridLayout22 = uigridlayout(app.Tab_12);
            app.UIAxes11 = uiaxes(app.GridLayout22);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes11.XDir = 'reverse';
            app.UIAxes13 = uiaxes(app.GridLayout22);
            title(app.UIAxes13, 'Title')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.UIAxes12 = uiaxes(app.GridLayout22);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.GridLayout25 = uigridlayout(app.GridLayout22);
            app.Button_8 = uibutton(app.GridLayout25, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '查看';
            app.holdonCheckBox = uicheckbox(app.GridLayout25);
            app.holdonCheckBox.Text = 'hold on';
            app.holdonCheckBox.Value = true;
            app.Tab_16 = uitab(app.TabGroup4);
            app.Tab_16.Title = '事件组别分类';
            app.GridLayout28 = uigridlayout(app.Tab_16);
            app.TabGroup6 = uitabgroup(app.GridLayout28);
            app.Tab_17 = uitab(app.TabGroup6);
            app.Tab_17.Title = '分组';
            app.GridLayout29 = uigridlayout(app.Tab_17);
            app.UIAxes14 = uiaxes(app.GridLayout29);
            title(app.UIAxes14, 'Title')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.ListBox = uilistbox(app.GridLayout29);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.Button_9 = uibutton(app.GridLayout29, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '检测';
            app.holdonCheckBox_2 = uicheckbox(app.GridLayout29);
            app.holdonCheckBox_2.Text = 'hold on';
            app.holdonCheckBox_2.Value = true;
            app.NaNLabel_5 = uilabel(app.GridLayout10);
            app.NaNLabel_5.Text = 'NaN';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = '绘制';
            app.Menu_3 = uimenu(app.Menu_2);
            app.Menu_3.Text = '信号';
            app.Menu_5 = uimenu(app.Menu_3);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @drawOriginalSignal, true);
            app.Menu_5.Text = '原始信号';
            app.Menu_6 = uimenu(app.Menu_3);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '预处理信号';
            app.Menu_4 = uimenu(app.Menu_2);
            app.Menu_4.Text = '频谱';
            app.Menu_7 = uimenu(app.Menu_4);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '原始信号频谱';
            app.Menu_8 = uimenu(app.Menu_4);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '预处理信号频谱';
            app.Menu_9 = uimenu(app.ContextMenu);
            app.Menu_9.Text = '坐标区调整';
            app.xMenu = uimenu(app.Menu_9);
            app.xMenu.Text = 'x轴';
            app.xMenu_2 = uimenu(app.xMenu);
            app.xMenu_2.Text = 'x轴单位';
            app.Menu_24 = uimenu(app.xMenu_2);
            app.Menu_24.Text = '时间';
            app.nsMenu_2 = uimenu(app.Menu_24);
            app.nsMenu_2.Text = 'ns';
            app.usMenu_3 = uimenu(app.Menu_24);
            app.usMenu_3.Text = 'us';
            app.msMenu_3 = uimenu(app.Menu_24);
            app.msMenu_3.Text = 'ms';
            app.sMenu_3 = uimenu(app.Menu_24);
            app.sMenu_3.Text = 's';
            app.Menu_25 = uimenu(app.xMenu_2);
            app.Menu_25.Text = '频率';
            app.HzMenu_2 = uimenu(app.Menu_25);
            app.HzMenu_2.Text = 'Hz';
            app.kHzMenu_2 = uimenu(app.Menu_25);
            app.kHzMenu_2.Text = 'kHz';
            app.yMenu = uimenu(app.Menu_9);
            app.yMenu.Text = 'y轴';
            app.yMenu_2 = uimenu(app.yMenu);
            app.yMenu_2.Text = 'y轴最大值';
            app.Menu_10 = uimenu(app.yMenu_2);
            app.Menu_10.Text = '0.3';
            app.Menu_11 = uimenu(app.yMenu_2);
            app.Menu_11.Text = '0.5';
            app.Menu_12 = uimenu(app.yMenu_2);
            app.Menu_12.Text = '1';
            app.Menu_13 = uimenu(app.yMenu_2);
            app.Menu_13.Text = '1.5';
            app.Menu_14 = uimenu(app.yMenu_2);
            app.Menu_14.Text = '2';
            app.Menu_15 = uimenu(app.yMenu_2);
            app.Menu_15.Text = '2.5';
            app.Menu_16 = uimenu(app.yMenu_2);
            app.Menu_16.Text = '3';
            app.Menu_17 = uimenu(app.yMenu_2);
            app.Menu_17.Text = '3.5';
            app.Menu_18 = uimenu(app.yMenu_2);
            app.Menu_18.Text = '4';
            app.Menu_19 = uimenu(app.yMenu_2);
            app.Menu_19.Text = '5';
            app.yMenu_3 = uimenu(app.yMenu);
            app.yMenu_3.Text = 'y轴单位';
            app.Menu_23 = uimenu(app.yMenu_3);
            app.Menu_23.Text = '电流';
            app.pAMenu_3 = uimenu(app.Menu_23);
            app.pAMenu_3.Text = 'pA';
            app.nAMenu_3 = uimenu(app.Menu_23);
            app.nAMenu_3.Text = 'nA';
            app.uAMenu_2 = uimenu(app.Menu_23);
            app.uAMenu_2.Text = 'uA';
            app.mAMenu_3 = uimenu(app.Menu_23);
            app.mAMenu_3.Text = 'mA';
            app.AMenu_3 = uimenu(app.Menu_23);
            app.AMenu_3.Text = 'A';
            % Assign app.ContextMenu
            app.UIAxes5.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_20 = uimenu(app.ContextMenu2);
            app.Menu_20.Text = '单位';
            app.xMenu_3 = uimenu(app.Menu_20);
            app.xMenu_3.Text = 'x轴';
            app.Menu_21 = uimenu(app.xMenu_3);
            app.Menu_21.Text = '时间';
            app.nsMenu = uimenu(app.Menu_21);
            app.nsMenu.Text = 'ns';
            app.usMenu_2 = uimenu(app.Menu_21);
            app.usMenu_2.Text = 'us';
            app.msMenu_2 = uimenu(app.Menu_21);
            app.msMenu_2.Text = 'ms';
            app.sMenu_2 = uimenu(app.Menu_21);
            app.sMenu_2.Text = 's';
            app.Menu_26 = uimenu(app.xMenu_3);
            app.Menu_26.Text = '频率';
            app.HzMenu_3 = uimenu(app.Menu_26);
            app.HzMenu_3.Text = 'Hz';
            app.kHzMenu_3 = uimenu(app.Menu_26);
            app.kHzMenu_3.Text = 'kHz';
            app.yMenu_4 = uimenu(app.Menu_20);
            app.yMenu_4.Text = 'y轴';
            app.Menu_22 = uimenu(app.yMenu_4);
            app.Menu_22.Text = '电流';
            app.pAMenu_2 = uimenu(app.Menu_22);
            app.pAMenu_2.Text = 'pA';
            app.nAMenu_2 = uimenu(app.Menu_22);
            app.nAMenu_2.Text = 'nA';
            app.uAMenu = uimenu(app.Menu_22);
            app.uAMenu.Text = 'uA';
            app.mAMenu_2 = uimenu(app.Menu_22);
            app.mAMenu_2.Text = 'mA';
            app.AMenu_2 = uimenu(app.Menu_22);
            app.AMenu_2.Text = 'A';
            % Assign app.ContextMenu2
            app.group1.ContextMenu = app.ContextMenu2;
            app.group1fft.ContextMenu = app.ContextMenu2;
            app.group2.ContextMenu = app.ContextMenu2;
            app.group2fft.ContextMenu = app.ContextMenu2;
            app.UIAxes6.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.XscaleMenu = uimenu(app.ContextMenu3);
            app.XscaleMenu.Text = 'Xscale';
            app.linearMenu = uimenu(app.XscaleMenu);
            app.linearMenu.Text = 'linear';
            app.logMenu = uimenu(app.XscaleMenu);
            app.logMenu.Text = 'log';
            app.YscaleMenu = uimenu(app.ContextMenu3);
            app.YscaleMenu.Text = 'Yscale';
            app.linearMenu_2 = uimenu(app.YscaleMenu);
            app.linearMenu_2.Text = 'linear';
            app.logMenu_2 = uimenu(app.YscaleMenu);
            app.logMenu_2.Text = 'log';
            % Assign app.ContextMenu3
            app.UIAxes12.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.Menu_27 = uimenu(app.ContextMenu4);
            app.Menu_27.Text = '文件';
            app.importFile = uimenu(app.Menu_27);
            app.importFile.MenuSelectedFcn = createCallbackFcn(app, @importFileSelected, true);
            app.importFile.Text = '导入...';
            app.clearFile = uimenu(app.Menu_27);
            app.clearFile.MenuSelectedFcn = createCallbackFcn(app, @clearFileSelected, true);
            app.clearFile.Text = '清空';
            app.Menu_31 = uimenu(app.ContextMenu4);
            app.Menu_31.Text = '参数调整';
            app.Menu_32 = uimenu(app.Menu_31);
            app.Menu_32.MenuSelectedFcn = createCallbackFcn(app, @Menu_32Selected, true);
            app.Menu_32.Text = '保存到...';
            app.Menu_33 = uimenu(app.Menu_31);
            app.Menu_33.MenuSelectedFcn = createCallbackFcn(app, @Menu_33Selected, true);
            app.Menu_33.Text = '从...读取参数';
            % Assign app.ContextMenu4
            app.DropDown.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end